classdef exampleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        y2TextArea              matlab.ui.control.TextArea
        y2TextAreaLabel         matlab.ui.control.Label
        EnchanceImageButton     matlab.ui.control.Button
        NewImageLabel           matlab.ui.control.Label
        OriginalImageLabel      matlab.ui.control.Label
        Image2_2                matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        x1TextArea              matlab.ui.control.TextArea
        x1TextAreaLabel         matlab.ui.control.Label
        x2TextArea              matlab.ui.control.TextArea
        x2TextAreaLabel         matlab.ui.control.Label
        y1TextArea              matlab.ui.control.TextArea
        y1TextAreaLabel         matlab.ui.control.Label
        ImageNameTextArea       matlab.ui.control.TextArea
        ImageNameTextAreaLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnchanceImageButton
        function EnchanceImageButtonPushed(app, event)
            x1 = str2double(app.x1TextArea.Value(1));
            x2 = str2double(app.x2TextArea.Value(1));
            y1 = str2double(app.y1TextArea.Value(1));
            y2 = str2double(app.y2TextArea.Value(1));
            image = app.Image2_2.ImageSource;
            newImage = imcrop(image, [x1, y1, x2, y2]);
            newImage = imresize(newImage, 1, "bicubic");
            app.Image2.ImageSource = newImage;
        end

        % Value changed function: ImageNameTextArea
        function ImageNameTextAreaValueChanged(app, event)
            value = app.ImageNameTextArea.Value{1,1};
            app.Image2_2.ImageSource = imread(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageNameTextAreaLabel = uilabel(app.UIFigure);
            app.ImageNameTextAreaLabel.Text = 'Image Name';
            app.ImageNameTextArea = uitextarea(app.UIFigure);
            app.ImageNameTextArea.ValueChangedFcn = createCallbackFcn(app, @ImageNameTextAreaValueChanged, true);
            app.ImageNameTextArea.Placeholder = 'Image Path Here';
            app.y1TextAreaLabel = uilabel(app.UIFigure);
            app.y1TextAreaLabel.Text = 'y1';
            app.y1TextArea = uitextarea(app.UIFigure);
            app.x2TextAreaLabel = uilabel(app.UIFigure);
            app.x2TextAreaLabel.Text = 'x2';
            app.x2TextArea = uitextarea(app.UIFigure);
            app.x1TextAreaLabel = uilabel(app.UIFigure);
            app.x1TextAreaLabel.Text = 'x1';
            app.x1TextArea = uitextarea(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2_2 = uiimage(app.UIFigure);
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.NewImageLabel = uilabel(app.UIFigure);
            app.NewImageLabel.Text = 'New Image';
            app.EnchanceImageButton = uibutton(app.UIFigure, 'push');
            app.EnchanceImageButton.ButtonPushedFcn = createCallbackFcn(app, @EnchanceImageButtonPushed, true);
            app.EnchanceImageButton.Text = 'Enchance Image';
            app.y2TextAreaLabel = uilabel(app.UIFigure);
            app.y2TextAreaLabel.Text = 'y2';
            app.y2TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exampleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end